classdef app_seriesRLC_res < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SeriesRLCResonanceSimulatorUIFigure  matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        AmplitudeVEditField          matlab.ui.control.NumericEditField
        AmplitudeLabel               matlab.ui.control.Label
        InitialPhaseDegreeEditField  matlab.ui.control.NumericEditField
        InitialPhaseDegreeLabel      matlab.ui.control.Label
        InputCFEditField             matlab.ui.control.NumericEditField
        InputCLabel                  matlab.ui.control.Label
        InputLHEditField             matlab.ui.control.NumericEditField
        InputLLabel                  matlab.ui.control.Label
        InputRohmEditField           matlab.ui.control.NumericEditField
        InputRohmEditFieldLabel      matlab.ui.control.Label
        HomeButton                   matlab.ui.control.Button
        QualityFactorLabel           matlab.ui.control.Label
        BandWidthradsLabel           matlab.ui.control.Label
        EditField2                   matlab.ui.control.NumericEditField
        EditField_2                  matlab.ui.control.NumericEditField
        ClearDataButton              matlab.ui.control.Button
        ShowPlotButton               matlab.ui.control.Button
        SeriesRLCResonanceLabel      matlab.ui.control.Label
        ResonantFrequencyHzLabel     matlab.ui.control.Label
        EditField                    matlab.ui.control.NumericEditField
        ShowOutputButton             matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ShowOutputButton
        function ShowOutputButtonPushed(app, event)
            r=app.InputRohmEditField.Value;
            l=app.InputLHEditField.Value;
            c=app.InputCFEditField.Value;
            amp=app.AmplitudeVEditField.Value;
            del=app.InitialPhaseDegreeEditField.Value;
            resw=1/sqrt(l*c);
            resf=resw/(2*pi);
            app.EditField.Value=resf;
            BW=r/l;         
            QF=resw/BW;
            app.EditField_2.Value=BW;
            app.EditField2.Value=QF;
        end

        % Button pushed function: ShowPlotButton
        function ShowPlotButtonPushed(app, event)
          r=app.InputRohmEditField.Value;
            l=app.InputLHEditField.Value;
            c=app.InputCFEditField.Value;
            amp=app.AmplitudeVEditField.Value;
            del=app.InitialPhaseDegreeEditField.Value;
            resw=1/sqrt(l*c);
            resf=resw/(2*pi);
            f=0:0.001:(2*resf);
            w=2*pi*f;
            Z=r+1i*(w*l-1./(w*c));
            itotal=(amp*exp(1i*((del*pi)/180)))./Z;
            Vr=itotal.*r;
            Vl=itotal.*(2*pi.*f).*l;
            Vc=itotal.*1./((2*pi.*f).*c);
            plot(app.UIAxes,f,abs(Vr),'r',f,abs(Vl),'g',f,abs(Vc),'b',resf,max(Vr));
            legend(app.UIAxes,'VR','VL','VC');
        end

        % Button pushed function: ClearDataButton
        function ClearDataButtonPushed(app, event)
            app.InputRohmEditField.Value=0;
            app.InputLHEditField.Value=0;
            app.InputCFEditField.Value=0;
            app.AmplitudeVEditField.Value=0;
            app.InitialPhaseDegreeEditField.Value=0;
            app.EditField.Value=0;
            app.EditField_2.Value=0;
            app.EditField2.Value=0;
            f=0:.1:1;
            Vr = ones(11);
            plot(app.UIAxes,f,Vr,"Color",[0.80,0.80,0.80]);
            legend(app.UIAxes,'hide');
        end

        % Button pushed function: HomeButton
        function HomeButtonPushed(app, event)
            circuitAnalyze.app.callingapp=Home;
            app.delete;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SeriesRLCResonanceSimulatorUIFigure = uifigure('Visible', 'off');
            app.SeriesRLCResonanceSimulatorUIFigure.Name = 'Series RLC Resonance Simulator';
            app.SeriesRLCResonanceSimulatorUIFigure.Icon = 'icon.jpg';
            app.GridLayout = uigridlayout(app.SeriesRLCResonanceSimulatorUIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'R, VL, VC, and I versus frequency for a series resonant circuit')
            xlabel(app.UIAxes, {'Frequency(Hz)-->'; ''})
            ylabel(app.UIAxes, 'Voltages(V)-->')
            zlabel(app.UIAxes, 'Z')
            app.ShowOutputButton = uibutton(app.GridLayout, 'push');
            app.ShowOutputButton.ButtonPushedFcn = createCallbackFcn(app, @ShowOutputButtonPushed, true);
            app.ShowOutputButton.Text = 'Show Output';
            app.EditField = uieditfield(app.GridLayout, 'numeric');
            app.ResonantFrequencyHzLabel = uilabel(app.GridLayout);
            app.ResonantFrequencyHzLabel.Text = 'Resonant Frequency (Hz)';
            app.SeriesRLCResonanceLabel = uilabel(app.GridLayout);
            app.SeriesRLCResonanceLabel.Text = 'Series RLC Resonance';
            app.ShowPlotButton = uibutton(app.GridLayout, 'push');
            app.ShowPlotButton.ButtonPushedFcn = createCallbackFcn(app, @ShowPlotButtonPushed, true);
            app.ShowPlotButton.Text = 'Show Plot';
            app.ClearDataButton = uibutton(app.GridLayout, 'push');
            app.ClearDataButton.ButtonPushedFcn = createCallbackFcn(app, @ClearDataButtonPushed, true);
            app.ClearDataButton.Text = 'Clear Data';
            app.EditField_2 = uieditfield(app.GridLayout, 'numeric');
            app.EditField2 = uieditfield(app.GridLayout, 'numeric');
            app.BandWidthradsLabel = uilabel(app.GridLayout);
            app.BandWidthradsLabel.Text = 'Band Width (rad/s)';
            app.QualityFactorLabel = uilabel(app.GridLayout);
            app.QualityFactorLabel.Text = 'Quality Factor';
            app.HomeButton = uibutton(app.GridLayout, 'push');
            app.HomeButton.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonPushed, true);
            app.HomeButton.Icon = 'home.png';
            app.HomeButton.Text = {'Home'; ''};
            app.InputRohmEditFieldLabel = uilabel(app.GridLayout);
            app.InputRohmEditFieldLabel.Text = 'Input R (ohm)';
            app.InputRohmEditField = uieditfield(app.GridLayout, 'numeric');
            app.InputLLabel = uilabel(app.GridLayout);
            app.InputLLabel.Text = 'Input L (H) ';
            app.InputLHEditField = uieditfield(app.GridLayout, 'numeric');
            app.InputCLabel = uilabel(app.GridLayout);
            app.InputCLabel.Text = 'Input  C (F)';
            app.InputCFEditField = uieditfield(app.GridLayout, 'numeric');
            app.InitialPhaseDegreeLabel = uilabel(app.GridLayout);
            app.InitialPhaseDegreeLabel.Text = {'Initial Phase'; '(Degree)'};
            app.InitialPhaseDegreeEditField = uieditfield(app.GridLayout, 'numeric');
            app.AmplitudeLabel = uilabel(app.GridLayout);
            app.AmplitudeLabel.Text = 'Amplitude (V)';
            app.AmplitudeVEditField = uieditfield(app.GridLayout, 'numeric');
            % Show the figure after all components are created
            app.SeriesRLCResonanceSimulatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_seriesRLC_res

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SeriesRLCResonanceSimulatorUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SeriesRLCResonanceSimulatorUIFigure)
        end
    end
end