classdef circuitAnalyze < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DCandACCircuitAnalyzerUIFigure  matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        HomeButton                      matlab.ui.control.Button
        TheveninOrNortonResistanceEditFieldLabel  matlab.ui.control.Label
        NortonCurrentIEditFieldLabel    matlab.ui.control.Label
        TheveninVoltageVEditFieldLabel  matlab.ui.control.Label
        NODE_2Spinner                   matlab.ui.control.Spinner
        TheveninOrNortonResistanceEditField  matlab.ui.control.NumericEditField
        NortonCurrentIEditField         matlab.ui.control.NumericEditField
        TheveninVoltageVEditField       matlab.ui.control.NumericEditField
        NODE_1Spinner                   matlab.ui.control.Spinner
        NODE_2SpinnerLabel              matlab.ui.control.Label
        NODE_1SpinnerLabel              matlab.ui.control.Label
        EnterNumberofnodesSpinner       matlab.ui.control.Spinner
        EnterNumberofnodesSpinnerLabel  matlab.ui.control.Label
        EnterElementNetlistCodeinthetextboxLabel  matlab.ui.control.Label
        TextArea_2                      matlab.ui.control.TextArea
        ResetButton                     matlab.ui.control.Button
        ShowButton                      matlab.ui.control.Button
        TheveninvoltageNortonCurrentCalculatorLabel  matlab.ui.control.Label
        NodeVoltagesCurrentsLabel       matlab.ui.control.Label
        DCsweepPanel                    matlab.ui.container.Panel
        ToEditField                     matlab.ui.control.NumericEditField
        ToEditFieldLabel                matlab.ui.control.Label
        FromEditField                   matlab.ui.control.NumericEditField
        FromEditFieldLabel              matlab.ui.control.Label
        Node_2Spinner                   matlab.ui.control.Spinner
        Node_2SpinnerLabel              matlab.ui.control.Label
        Node_1Spinner                   matlab.ui.control.Spinner
        Node_1SpinnerLabel              matlab.ui.control.Label
        XaxisEditField                  matlab.ui.control.EditField
        XaxisEditFieldLabel             matlab.ui.control.Label
        ShowPlotButton                  matlab.ui.control.Button
        YaxischaracteristicsEntertheNodesLabel  matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        SweepRangeLabel                 matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        TextArea                        matlab.ui.control.TextArea
        ENDButton                       matlab.ui.control.Button
        InputNodeButton                 matlab.ui.control.Button
        DCACCircuitSolverLabel          matlab.ui.control.Label
    end

    
    properties (Access = private)
        n=0;
        lhs=[];
        rhs=[];
        nodesofVDC=[];
        branch=[];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changing function: EnterNumberofnodesSpinner
        function EnterNumberofnodesSpinnerValueChanging(app, event)
            app.InputNodeButton.Enable='on';
        end

        % Callback function
        function EnterElementNetlistCodeEditFieldValueChanged(app, event)
            SaveAttributeButtonPushed(app, event);   
        end

        % Callback function
        function SaveAttributeButtonPushed(app, event)
            st=app.EnterElementNetlistCodeEditField.Value;
            st=strip(st);
            if ~isempty(st)
                [elementName, attributes, app.nodesofVDC, app.branch]=handleInput(app.nodesofVDC, app.branch, st);
                [app.lhs, app.rhs]=callStampers(elementName, attributes,app.lhs, app.rhs);
            end
            app.EnterElementNetlistCodeEditField.Value='';
        end

        % Button pushed function: InputNodeButton
        function InputNodeButtonPushed(app, event)
            app.n=app.EnterNumberofnodesSpinner.Value;
            [app.lhs, app.rhs, app.nodesofVDC, app.branch]=initiate(app.n);
            app.TextArea.Value='';
            app.InputNodeButton.Enable='off';
        end

        % Button pushed function: ENDButton
        function ENDButtonPushed(app, event)
            val=app.TextArea_2.Value;
            [app.lhs, app.rhs, app.nodesofVDC, app.branch] = handleInput_0(app.nodesofVDC, app.branch, app.lhs, app.rhs, val);
            st=solve_it(app.lhs, app.rhs, app.branch, app.n);
            disp(st);
            app.TextArea.Value=st;
        end

        % Button pushed function: ShowButton
        function ShowButtonPushed(app, event)
            n1=app.NODE_1Spinner.Value;
            n2=app.NODE_2Spinner.Value;
            [vth, rth]=thievenin(app.lhs, app.rhs, n1, n2);
            app.TheveninVoltageVEditField.Value=vth;
            app.TheveninOrNortonResistanceEditField.Value=rth;
            app.NortonCurrentIEditField.Value=vth/rth;
            
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.NODE_1Spinner.Value=0;
            app.NODE_2Spinner.Value=0;
            app.TheveninVoltageVEditField.Value=0;
            app.TheveninOrNortonResistanceEditField.Value=0;
            app.NortonCurrentIEditField.Value=0;
        end

        % Button pushed function: ShowPlotButton
        function ShowPlotButtonPushed(app, event)
            n1=app.Node_1Spinner.Value;
            n2=app.Node_2Spinner.Value;
            vname=app.XaxisEditField.Value;
            range1=app.FromEditField.Value;
            range2=app.ToEditField.Value;
            [pltx, plty]=dcSweep(app.lhs, app.rhs, vname, n1, n2, range1, range2, app.branch);
            plot(app.UIAxes,pltx,plty);
             xlabel(app.UIAxes, upper(vname));
            ylabel(app.UIAxes, "V("+num2str(n1)+":"+num2str(n2)+")")
            
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Button pushed function: HomeButton
        function HomeButtonPushed(app, event)
            circuitAnalyze.app.callingapp=Home;
            app.delete;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DCandACCircuitAnalyzerUIFigure = uifigure('Visible', 'off');
            app.DCandACCircuitAnalyzerUIFigure.Name = 'DC and AC Circuit Analyzer';
            app.DCandACCircuitAnalyzerUIFigure.Icon = 'icon.jpg';
            app.GridLayout = uigridlayout(app.DCandACCircuitAnalyzerUIFigure);
            app.DCACCircuitSolverLabel = uilabel(app.GridLayout);
            app.DCACCircuitSolverLabel.Text = 'DC & AC Circuit Solver';
            app.InputNodeButton = uibutton(app.GridLayout, 'push');
            app.InputNodeButton.ButtonPushedFcn = createCallbackFcn(app, @InputNodeButtonPushed, true);
            app.InputNodeButton.Text = 'Input Node';
            app.ENDButton = uibutton(app.GridLayout, 'push');
            app.ENDButton.ButtonPushedFcn = createCallbackFcn(app, @ENDButtonPushed, true);
            app.ENDButton.Text = {'END'; ''};
            app.TextArea = uitextarea(app.GridLayout);
            app.DCsweepPanel = uipanel(app.GridLayout);
            app.DCsweepPanel.Title = 'DC sweep';
            app.UIAxes = uiaxes(app.DCsweepPanel);
            title(app.UIAxes, {''; ''})
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.SweepRangeLabel = uilabel(app.DCsweepPanel);
            app.SweepRangeLabel.Text = 'Sweep Range--';
            app.Label = uilabel(app.DCsweepPanel);
            app.Label.Text = '';
            app.YaxischaracteristicsEntertheNodesLabel = uilabel(app.DCsweepPanel);
            app.YaxischaracteristicsEntertheNodesLabel.Text = 'Y- axis characteristics (Enter the Nodes)';
            app.ShowPlotButton = uibutton(app.DCsweepPanel, 'push');
            app.ShowPlotButton.ButtonPushedFcn = createCallbackFcn(app, @ShowPlotButtonPushed, true);
            app.ShowPlotButton.Text = 'Show Plot';
            app.XaxisEditFieldLabel = uilabel(app.DCsweepPanel);
            app.XaxisEditFieldLabel.Text = 'X- axis';
            app.XaxisEditField = uieditfield(app.DCsweepPanel, 'text');
            app.Node_1SpinnerLabel = uilabel(app.DCsweepPanel);
            app.Node_1SpinnerLabel.Text = 'Node_1';
            app.Node_1Spinner = uispinner(app.DCsweepPanel);
            app.Node_2SpinnerLabel = uilabel(app.DCsweepPanel);
            app.Node_2SpinnerLabel.Text = 'Node_2';
            app.Node_2Spinner = uispinner(app.DCsweepPanel);
            app.FromEditFieldLabel = uilabel(app.DCsweepPanel);
            app.FromEditFieldLabel.Text = 'From';
            app.FromEditField = uieditfield(app.DCsweepPanel, 'numeric');
            app.ToEditFieldLabel = uilabel(app.DCsweepPanel);
            app.ToEditFieldLabel.Text = 'To';
            app.ToEditField = uieditfield(app.DCsweepPanel, 'numeric');
            app.NodeVoltagesCurrentsLabel = uilabel(app.GridLayout);
            app.NodeVoltagesCurrentsLabel.Text = 'Node Voltages & Currents';
            app.TheveninvoltageNortonCurrentCalculatorLabel = uilabel(app.GridLayout);
            app.TheveninvoltageNortonCurrentCalculatorLabel.Text = {'Thevenin voltage &'; 'Norton Current Calculator'};
            app.ShowButton = uibutton(app.GridLayout, 'push');
            app.ShowButton.ButtonPushedFcn = createCallbackFcn(app, @ShowButtonPushed, true);
            app.ShowButton.Text = 'Show';
            app.ResetButton = uibutton(app.GridLayout, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.TextArea_2 = uitextarea(app.GridLayout);
            app.EnterElementNetlistCodeinthetextboxLabel = uilabel(app.GridLayout);
            app.EnterElementNetlistCodeinthetextboxLabel.Text = 'Enter Element(Netlist Code) in the text box:';
            app.EnterNumberofnodesSpinnerLabel = uilabel(app.GridLayout);
            app.EnterNumberofnodesSpinnerLabel.Text = 'Enter Number of nodes';
            app.EnterNumberofnodesSpinner = uispinner(app.GridLayout);
            app.EnterNumberofnodesSpinner.ValueChangingFcn = createCallbackFcn(app, @EnterNumberofnodesSpinnerValueChanging, true);
            app.NODE_1SpinnerLabel = uilabel(app.GridLayout);
            app.NODE_1SpinnerLabel.Text = {'NODE_1'; ''};
            app.NODE_2SpinnerLabel = uilabel(app.GridLayout);
            app.NODE_2SpinnerLabel.Text = {'NODE_2'; ''};
            app.NODE_1Spinner = uispinner(app.GridLayout);
            app.TheveninVoltageVEditField = uieditfield(app.GridLayout, 'numeric');
            app.NortonCurrentIEditField = uieditfield(app.GridLayout, 'numeric');
            app.TheveninOrNortonResistanceEditField = uieditfield(app.GridLayout, 'numeric');
            app.NODE_2Spinner = uispinner(app.GridLayout);
            app.TheveninVoltageVEditFieldLabel = uilabel(app.GridLayout);
            app.TheveninVoltageVEditFieldLabel.Text = 'Thevenin Voltage (V)';
            app.NortonCurrentIEditFieldLabel = uilabel(app.GridLayout);
            app.NortonCurrentIEditFieldLabel.Text = 'Norton Current (I)';
            app.TheveninOrNortonResistanceEditFieldLabel = uilabel(app.GridLayout);
            app.TheveninOrNortonResistanceEditFieldLabel.Text = {'Thevenin (Or Norton)'; 'Resistance'};
            app.HomeButton = uibutton(app.GridLayout, 'push');
            app.HomeButton.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonPushed, true);
            app.HomeButton.Icon = 'home.png';
            app.HomeButton.Text = {'Home'; ''};
            % Show the figure after all components are created
            app.DCandACCircuitAnalyzerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = circuitAnalyze

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.DCandACCircuitAnalyzerUIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.DCandACCircuitAnalyzerUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DCandACCircuitAnalyzerUIFigure)
        end
    end
end